classdef WeightMeasureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ActionsDropDownLabel       matlab.ui.control.Label
        ActionsDropDown            matlab.ui.control.DropDown
        CurrentActionDisplayPanel  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        arduino = arduino();
        isInCurrentMeasure = false;
        dailyTimer % Description
    end
    
    methods (Access = private)
        
        function currentMeasure(app)     
            app.isInCurrentMeasure = true;
            
            stop(app.dailyTimer);
            
            ax = uiaxes(app.CurrentActionDisplayPanel);
            ax.Position = [ax.Position(1:2) [app.CurrentActionDisplayPanel.Position(3:4) - 3.5*ax.Position(1:2)]];
            xLabel = 'Elapsed Time (s)';
            yLabel = 'Weight (Kg)';
            yMax  = 4;
            yMin  = 0;
            plotGrid = 'on';
        
            bufferSize = 60;
            time_plot = zeros(1, bufferSize);
            data_plot = zeros(1, bufferSize);
        
            plotGraph = plot(ax, 0,0,'-r');
            xlabel(ax, xLabel);
            ylabel(ax, yLabel);
            ylim(ax, [yMin yMax]);
            grid(ax, plotGrid);
        
            tic;
            while app.isInCurrentMeasure
                voltage = app.arduino.readVoltage('A0');
                weight = FSRValue(voltage);
                
                disp(voltage);
        
                time_plot = [time_plot(2:end) toc];
                data_plot = [data_plot(2:end) weight];
                set(plotGraph, 'XData', time_plot, 'YData', data_plot);    
                xlim(ax, [time_plot(1) time_plot(end)]);
            end
            
            start(app.dailyTimer);
        end
        
        function dailyMeasure(app, obj, event, string_arg)
            filename = strcat('weights-', datestr(datetime('today')));
            voltage = app.arduino.readVoltage('A0');
            weight = FSRValue(voltage);
            currentDateTime = datetime;
            
            disp('Updating weights file...');
    
            if ~exist(filename, 'file')
                save(filename, 'currentDateTime', 'weight');
            else
                m = matfile(filename,'Writable',true);
                m.currentDateDime(end+1) = currentDateTime;
                m.weight(end+1) = weight;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ActionsDropDown
        function ActionsDropDownValueChanged(app, event)
            value = app.ActionsDropDown.Value;
            switch value
                case "Real Time Measure"
                    currentMeasure(app);
                case "Start Saving Daily Data"
                    app.isInCurrentMeasure = false;
                    if isempty(app.dailyTimer)
                        app.dailyTimer = timer('StartDelay', 4, 'Period', 500e-3, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate', 'UserData', app);
                        app.dailyTimer.TimerFcn = @app.dailyMeasure;
                        
                        start(app.dailyTimer);
                    end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ActionsDropDownLabel = uilabel(app.UIFigure);
            app.ActionsDropDownLabel.Text = 'Actions';
            app.ActionsDropDown = uidropdown(app.UIFigure);
            app.ActionsDropDown.Items = {'Real Time Measure', 'Start Saving Daily Data', 'Option 3', 'Option 4'};
            app.ActionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionsDropDownValueChanged, true);
            app.ActionsDropDown.Value = 'Real Time Measure';
            app.CurrentActionDisplayPanel = uipanel(app.UIFigure);
            app.CurrentActionDisplayPanel.Title = 'Current Action Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeightMeasureApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end